classdef LiME < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LiMELogicinMemoryEmulatorLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        EmulatorconfigTab               matlab.ui.container.Tab
        EmulatorSettingsPanel           matlab.ui.container.Panel
        ZYNQcllockMHzEditFieldLabel     matlab.ui.control.Label
        ZYNQcllockMHzEditField          matlab.ui.control.NumericEditField
        AXImemoryclockMHzEditFieldLabel  matlab.ui.control.Label
        AXImemoryclockMHzEditField      matlab.ui.control.NumericEditField
        ReadAccessDelaycyclesEditFieldLabel  matlab.ui.control.Label
        ReadAccessDelaycyclesEditField  matlab.ui.control.NumericEditField
        WriteAccessDelaycyclesEditFieldLabel  matlab.ui.control.Label
        WriteAccessDelaycyclesEditField  matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        EmulatedparametersPanel         matlab.ui.container.Panel
        ProcessorcllockMHzEditFieldLabel  matlab.ui.control.Label
        ProcessorcllockMHzEditField     matlab.ui.control.NumericEditField
        MemoryBandwidthGbsecEditField_2Label  matlab.ui.control.Label
        MemoryBandwidthGbsecEditField_2  matlab.ui.control.NumericEditField
        ReadAccessDelaynsecEditField_2Label  matlab.ui.control.Label
        ReadAccessDelaynsecEditField_2  matlab.ui.control.NumericEditField
        WriteAccessDelaynsecEditField_2Label  matlab.ui.control.Label
        WriteAccessDelaynsecEditField_2  matlab.ui.control.NumericEditField
        PresetsDropDownLabel            matlab.ui.control.Label
        PresetsDropDown                 matlab.ui.control.DropDown
        FPGACOMPORTDropDownLabel        matlab.ui.control.Label
        FPGACOMPORTDropDown             matlab.ui.control.DropDown
        MHzLabel                        matlab.ui.control.Label
        ReadDelay120nsecWriteDelay120nsecLabel  matlab.ui.control.Label
        bd                              matlab.ui.control.UIAxes
        TestbenchconfigTab              matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        TestbenchDropDownLabel          matlab.ui.control.Label
        TestbenchDropDown               matlab.ui.control.DropDown
        SweepDropDownLabel              matlab.ui.control.Label
        SweepDropDown                   matlab.ui.control.DropDown
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        RUNButton                       matlab.ui.control.Button
        StatisticTab                    matlab.ui.container.Tab
        WriteTransactionCountEditFieldLabel  matlab.ui.control.Label
        WriteTransactionCountEditField  matlab.ui.control.NumericEditField
        ReadTransactionCountEditFieldLabel  matlab.ui.control.Label
        ReadTransactionCountEditField   matlab.ui.control.NumericEditField
        AverageWritedelaycyclesEditFieldLabel  matlab.ui.control.Label
        AverageWritedelaycyclesEditField  matlab.ui.control.NumericEditField
        AverageReaddelaycyclesEditFieldLabel  matlab.ui.control.Label
        AverageReaddelaycyclesEditField  matlab.ui.control.NumericEditField
        Num_BValidsEditFieldLabel       matlab.ui.control.Label
        Num_BValidsEditField            matlab.ui.control.NumericEditField
        TRACEButton                     matlab.ui.control.Button
        bd2                             matlab.ui.control.UIAxes
        logo1                           matlab.ui.control.UIAxes
        logo2                           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        uart
        u
    end
    
    methods (Access = private)
        
        function [] = update_params(app)
            cpu_freq = app.ZYNQcllockMHzEditField.Value;
            axi_clk = app.AXImemoryclockMHzEditField.Value;
            rd_delay = app.ReadAccessDelaycyclesEditField.Value;
            wr_delay = app.WriteAccessDelaycyclesEditField.Value;
            scale = app.ScaleFactorEditField.Value;
            
            app.ProcessorcllockMHzEditField.Value = cpu_freq*scale;
            app.MemoryBandwidthGbsecEditField_2.Value = axi_clk*scale*32/1000;
            app.ReadAccessDelaynsecEditField_2.Value = rd_delay/axi_clk*1000/scale + 1/axi_clk/scale*1000;
            app.WriteAccessDelaynsecEditField_2.Value = wr_delay/axi_clk*1000/scale + 1/axi_clk/scale*1000;
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.uart = serial('COM5', 'BaudRate', 115200);
%             fopen(app.uart);
            
            app.u = udp('192.168.1.10',5001);
            app.u.LocalPort = 5200;
            app.u.InputBufferSize = 2^20;
            
            
            imshow('ZYNQ_bd.jpg','Parent', app.bd);
            imshow('RISCV.png','Parent', app.bd2);
            imshow('asic2_logo.png','Parent', app.logo1);
            imshow('Technion_logo.jpg','Parent', app.logo2);

            update_params(app);
        end

        % Value changed function: AXImemoryclockMHzEditField
        function AXImemoryclockMHzEditFieldValueChanged(app, event)
            update_params(app);
        end

        % Value changed function: ScaleFactorEditField
        function ScaleFactorEditFieldValueChanged(app, event)
            update_params(app);         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.uart);
            delete(app) 
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            num_iters = app.IterationsEditField.Value;
            wdelay = app.WriteAccessDelaycyclesEditField.Value;
            rddelay = app.ReadAccessDelaycyclesEditField.Value;
            axi_clk = app.AXImemoryclockMHzEditField.Value;
            scale = app.ScaleFactorEditField.Value;
            sweep_mode = app.SweepDropDown.Value;
            
            switch app.TestbenchDropDown.Value
                case 'randa'
                    fprintf(app.uart, 'set_size 4000000');
                case 'image'
                    fprintf(app.uart, 'set_size 3000000');
                case 'pager'
                    fprintf(app.uart, 'set_size 12000000');
                case 'dgemm'
                    fprintf(app.uart, 'set_size 8000000');
                case 'strm'
                    fprintf(app.uart, 'set_size 6000000');
            end
                    
            if(num_iters == 1)
                fopen(app.u);
                fid = fopen('log.txt', 'w');
                fprintf(fid, 'Addr ID Length Time\n');
            end
            
            if(app.uart.BytesAvailable > 0)
                fread(app.uart, app.uart.BytesAvailable);
            end
            
            if(strcmp(sweep_mode, 'Bandwidth'))
                div_vec = fliplr(linspace(0, 0.99, num_iters));
                ticks = 27*20./(10 + div_vec);
                xlabel(app.UIAxes, 'Bandwidth [Gbit/sec]');
                xticks(app.UIAxes, ticks);
            else
                delay_vec = wdelay - floor(num_iters/2) : wdelay + ceil(num_iters/2);
                delay_vec = delay_vec .* (delay_vec >= 0);
                xlabel(app.UIAxes, 'Access Delay [nsec]');
                ticks = delay_vec/axi_clk*1000/scale + 1/axi_clk/scale*1000;
                xticks(app.UIAxes, ticks);
            end
            
            app.RUNButton.Text = 'RUNNING';
            app.RUNButton.FontColor = 'red';
   
            t = [];
            for i = 1:num_iters
                if(strcmp(sweep_mode, 'Bandwidth'))
                    fprintf(app.uart, 'set_clkdiv');
                    fprintf(app.uart, '%d', round(div_vec(i)*1000));
                else
                    fprintf(app.uart, 'set_wdelay');
                    fprintf(app.uart, '%d', delay_vec(i));
                end
          
                fprintf(app.uart, 'start');
                while(app.uart.BytesAvailable ==0)
                end
                str = fscanf(app.uart);
                str = strsplit(str);
                t = [t str2double(str{5})];
                bar(app.UIAxes, ticks(1:i), t, 0.05)
                drawnow
                
                str = fscanf(app.uart);
                str = str2double(strsplit(str));
                n_reads = str(1);
                n_writes = str(2);
                bytes_readen = str(3);
                bytes_writen = str(4);
                total_rd_delay = str(5);
                total_wr_delay = str(6);
                b_bvalids = str(7);
                app.WriteTransactionCountEditField.Value = n_reads;
                app.ReadTransactionCountEditField.Value = n_writes;
                app.Num_BValidsEditField.Value = b_bvalids;
%                 app.AverageReaddelaycyclesEditField.Value = total_rd_delay/bytes_writen*4;
%                 app.AverageWritedelaycyclesEditField.Value = total_wr_delay/bytes_readen*4;
                
            end
            
            if(num_iters == 1)
                data = fread(app.u, 1024, 'uint8');
                fclose(app.u);
                data = typecast(uint8(data), 'uint64');
                d = dec2bin(data, 64);              
                addr = bin2dec(d(:,1:32));
                id = bin2dec(d(:,33:38));
                l = bin2dec(d(:,39:42));
                time = bin2dec(d(:,43:63));
                flag = bin2dec(d(:,64));
                
                
                for(i = 1:1024/8)
                    fprintf(fid, '%s ', dec2hex(addr(i)));
                    fprintf(fid, '%d ', id(i));
                    fprintf(fid, '%d ', (l(i)+1)*4);
                    fprintf(fid, '%d\n', time(i));
                end
                
                fclose(fid)
            end

            app.RUNButton.Text = 'RUN';
            app.RUNButton.FontColor = 'green';
        end

        % Value changed function: WriteAccessDelaycyclesEditField
        function WriteAccessDelaycyclesEditFieldValueChanged(app, event)
            update_params(app); 
        end

        % Value changed function: ReadAccessDelaycyclesEditField
        function ReadAccessDelaycyclesEditFieldValueChanged(app, event)
            update_params(app); 
        end

        % Value changed function: PresetsDropDown
        function PresetsDropDownValueChanged(app, event)
            value = app.PresetsDropDown.Value;
            switch(value)
                case 'STT MRAM'
                    app.AXImemoryclockMHzEditField.Value = 27;
                    app.ReadAccessDelaycyclesEditField.Value = 3;
                    app.WriteAccessDelaycyclesEditField.Value = 3;
                    app.ScaleFactorEditField.Value = 50;
                    update_params(app);
            end
        end

        % Button pushed function: TRACEButton
        function TRACEButtonPushed(app, event)
            system('notepad log.txt')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LiMELogicinMemoryEmulatorLabel = uilabel(app.UIFigure);
            app.LiMELogicinMemoryEmulatorLabel.Text = 'LiME (Logic in Memory Emulator)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EmulatorconfigTab = uitab(app.TabGroup);
            app.EmulatorconfigTab.Title = 'Emulator config';
            app.EmulatorSettingsPanel = uipanel(app.EmulatorconfigTab);
            app.EmulatorSettingsPanel.Title = 'Emulator Settings';
            app.ZYNQcllockMHzEditFieldLabel = uilabel(app.EmulatorSettingsPanel);
            app.ZYNQcllockMHzEditFieldLabel.Text = 'ZYNQ cllock [MHz]';
            app.ZYNQcllockMHzEditField = uieditfield(app.EmulatorSettingsPanel, 'numeric');
            app.ZYNQcllockMHzEditField.Value = 667;
            app.AXImemoryclockMHzEditFieldLabel = uilabel(app.EmulatorSettingsPanel);
            app.AXImemoryclockMHzEditFieldLabel.Text = 'AXI memory clock [MHz]';
            app.AXImemoryclockMHzEditField = uieditfield(app.EmulatorSettingsPanel, 'numeric');
            app.AXImemoryclockMHzEditField.ValueChangedFcn = createCallbackFcn(app, @AXImemoryclockMHzEditFieldValueChanged, true);
            app.AXImemoryclockMHzEditField.Value = 100;
            app.ReadAccessDelaycyclesEditFieldLabel = uilabel(app.EmulatorSettingsPanel);
            app.ReadAccessDelaycyclesEditFieldLabel.Text = 'Read Access Delay [cycles]';
            app.ReadAccessDelaycyclesEditField = uieditfield(app.EmulatorSettingsPanel, 'numeric');
            app.ReadAccessDelaycyclesEditField.ValueDisplayFormat = '%.0f';
            app.ReadAccessDelaycyclesEditField.ValueChangedFcn = createCallbackFcn(app, @ReadAccessDelaycyclesEditFieldValueChanged, true);
            app.ReadAccessDelaycyclesEditField.Value = 10;
            app.WriteAccessDelaycyclesEditFieldLabel = uilabel(app.EmulatorSettingsPanel);
            app.WriteAccessDelaycyclesEditFieldLabel.Text = 'Write Access Delay [cycles]';
            app.WriteAccessDelaycyclesEditField = uieditfield(app.EmulatorSettingsPanel, 'numeric');
            app.WriteAccessDelaycyclesEditField.ValueDisplayFormat = '%.0f';
            app.WriteAccessDelaycyclesEditField.ValueChangedFcn = createCallbackFcn(app, @WriteAccessDelaycyclesEditFieldValueChanged, true);
            app.WriteAccessDelaycyclesEditField.Value = 10;
            app.ScaleFactorEditFieldLabel = uilabel(app.EmulatorSettingsPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.EmulatorSettingsPanel, 'numeric');
            app.ScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorEditFieldValueChanged, true);
            app.ScaleFactorEditField.Value = 20;
            app.EmulatedparametersPanel = uipanel(app.EmulatorconfigTab);
            app.EmulatedparametersPanel.Title = 'Emulated parameters';
            app.ProcessorcllockMHzEditFieldLabel = uilabel(app.EmulatedparametersPanel);
            app.ProcessorcllockMHzEditFieldLabel.Visible = 'off';
            app.ProcessorcllockMHzEditFieldLabel.Text = 'Processor cllock [MHz]';
            app.ProcessorcllockMHzEditField = uieditfield(app.EmulatedparametersPanel, 'numeric');
            app.ProcessorcllockMHzEditField.Editable = 'off';
            app.ProcessorcllockMHzEditField.Visible = 'off';
            app.ProcessorcllockMHzEditField.Value = 6670;
            app.MemoryBandwidthGbsecEditField_2Label = uilabel(app.EmulatedparametersPanel);
            app.MemoryBandwidthGbsecEditField_2Label.Text = 'Memory Bandwidth [Gb/sec]';
            app.MemoryBandwidthGbsecEditField_2 = uieditfield(app.EmulatedparametersPanel, 'numeric');
            app.MemoryBandwidthGbsecEditField_2.Editable = 'off';
            app.MemoryBandwidthGbsecEditField_2.Value = 32;
            app.ReadAccessDelaynsecEditField_2Label = uilabel(app.EmulatedparametersPanel);
            app.ReadAccessDelaynsecEditField_2Label.Text = 'Read Access Delay [nsec]';
            app.ReadAccessDelaynsecEditField_2 = uieditfield(app.EmulatedparametersPanel, 'numeric');
            app.ReadAccessDelaynsecEditField_2.Editable = 'off';
            app.ReadAccessDelaynsecEditField_2.Value = 12;
            app.WriteAccessDelaynsecEditField_2Label = uilabel(app.EmulatedparametersPanel);
            app.WriteAccessDelaynsecEditField_2Label.Text = 'Write Access Delay [nsec]';
            app.WriteAccessDelaynsecEditField_2 = uieditfield(app.EmulatedparametersPanel, 'numeric');
            app.WriteAccessDelaynsecEditField_2.Editable = 'off';
            app.WriteAccessDelaynsecEditField_2.Value = 12;
            app.PresetsDropDownLabel = uilabel(app.EmulatorconfigTab);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.EmulatorconfigTab);
            app.PresetsDropDown.Items = {'STT MRAM', 'MEM2', 'MEM3', 'MEM4'};
            app.PresetsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetsDropDownValueChanged, true);
            app.PresetsDropDown.Value = 'STT MRAM';
            app.FPGACOMPORTDropDownLabel = uilabel(app.EmulatorconfigTab);
            app.FPGACOMPORTDropDownLabel.Text = 'FPGA COM PORT';
            app.FPGACOMPORTDropDown = uidropdown(app.EmulatorconfigTab);
            app.FPGACOMPORTDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.FPGACOMPORTDropDown.Value = 'COM1';
            app.MHzLabel = uilabel(app.EmulatorconfigTab);
            app.MHzLabel.Text = '100 MHz';
            app.ReadDelay120nsecWriteDelay120nsecLabel = uilabel(app.EmulatorconfigTab);
            app.ReadDelay120nsecWriteDelay120nsecLabel.Text = {'Read Delay = 120 nsec'; 'Write Delay = 120 nsec'};
            app.bd = uiaxes(app.EmulatorconfigTab);
            app.TestbenchconfigTab = uitab(app.TabGroup);
            app.TestbenchconfigTab.Title = 'Testbench config';
            app.UIAxes = uiaxes(app.TestbenchconfigTab);
            ylabel(app.UIAxes, 'Emulated time [sec]')
            app.Panel = uipanel(app.TestbenchconfigTab);
            app.TestbenchDropDownLabel = uilabel(app.Panel);
            app.TestbenchDropDownLabel.Text = 'Testbench';
            app.TestbenchDropDown = uidropdown(app.Panel);
            app.TestbenchDropDown.Items = {'randa', 'image', 'pager', 'dgemm', 'strm'};
            app.TestbenchDropDown.Value = 'randa';
            app.SweepDropDownLabel = uilabel(app.Panel);
            app.SweepDropDownLabel.Text = 'Sweep';
            app.SweepDropDown = uidropdown(app.Panel);
            app.SweepDropDown.Items = {'Bandwidth', 'Read Access', 'Write Access', 'Read/Write Access'};
            app.SweepDropDown.Value = 'Bandwidth';
            app.IterationsEditFieldLabel = uilabel(app.Panel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.Panel, 'numeric');
            app.IterationsEditField.Value = 10;
            app.RUNButton = uibutton(app.TestbenchconfigTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.StatisticTab = uitab(app.TabGroup);
            app.StatisticTab.Title = 'Statistic';
            app.WriteTransactionCountEditFieldLabel = uilabel(app.StatisticTab);
            app.WriteTransactionCountEditFieldLabel.Text = 'Write Transaction Count';
            app.WriteTransactionCountEditField = uieditfield(app.StatisticTab, 'numeric');
            app.WriteTransactionCountEditField.ValueDisplayFormat = '%.0f';
            app.ReadTransactionCountEditFieldLabel = uilabel(app.StatisticTab);
            app.ReadTransactionCountEditFieldLabel.Text = 'Read Transaction Count';
            app.ReadTransactionCountEditField = uieditfield(app.StatisticTab, 'numeric');
            app.ReadTransactionCountEditField.ValueDisplayFormat = '%.0f';
            app.AverageWritedelaycyclesEditFieldLabel = uilabel(app.StatisticTab);
            app.AverageWritedelaycyclesEditFieldLabel.Text = 'Average Write delay [cycles]';
            app.AverageWritedelaycyclesEditField = uieditfield(app.StatisticTab, 'numeric');
            app.AverageReaddelaycyclesEditFieldLabel = uilabel(app.StatisticTab);
            app.AverageReaddelaycyclesEditFieldLabel.Text = 'Average Read delay [cycles]';
            app.AverageReaddelaycyclesEditField = uieditfield(app.StatisticTab, 'numeric');
            app.Num_BValidsEditFieldLabel = uilabel(app.StatisticTab);
            app.Num_BValidsEditFieldLabel.Text = 'Num_BValids';
            app.Num_BValidsEditField = uieditfield(app.StatisticTab, 'numeric');
            app.TRACEButton = uibutton(app.StatisticTab, 'push');
            app.TRACEButton.ButtonPushedFcn = createCallbackFcn(app, @TRACEButtonPushed, true);
            app.TRACEButton.Text = 'TRACE';
            app.bd2 = uiaxes(app.StatisticTab);
            app.logo1 = uiaxes(app.UIFigure);
            app.logo2 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = LiME

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end